classdef Malaria_D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Label                          matlab.ui.control.Label
        DetectionResultEditField       matlab.ui.control.EditField
        DetectionResultEditFieldLabel  matlab.ui.control.Label
        InfectedEditField              matlab.ui.control.EditField
        InfectedEditFieldLabel         matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        DetectButton                   matlab.ui.control.Button
        SefmentationButton             matlab.ui.control.Button
        preprocessButton               matlab.ui.control.Button
        InputButton                    matlab.ui.control.Button
        UIAxes_5                       matlab.ui.control.UIAxes
        UIAxes_4                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Description
        img2 % Description
        img3 % Description
        img4 % Description
        img5 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InputButton
        function inputIMG(app, event)
            [file, path] = uigetfile('*.*');
            if isequal (file,0)
                figure(app.UIFigure);
                return;
            end
            figure(app.UIFigure);
            imageT = fullfile(path,file);
            app.img = imread(imageT);
            imshow(app.img,'parent',app.UIAxes);
            expandAxes(app.UIAxes);
        end

        % Button pushed function: preprocessButton
        function preProcess(app, event)
            refImg = imread('dat\B_MO1_thin_giemsa6.jpg');
            matchedImage = imhistmatch(app.img,refImg);
            app.img2 = rgb2gray(matchedImage);
            imshow(app.img2,'parent',app.UIAxes_2);
            expandAxes(app.UIAxes_2);
        end

        % Button pushed function: SefmentationButton
        function segmentation(app, event)
            %watershed
            
            cellMask = segmentImageFcn(app.img);
            cellMask = bwareaopen(cellMask,30);
               
            %gray = rgb2gray(app.img);
            newGray = imhmin(rgb2gray(app.img),13);
            wsImg = watershed(newGray);
            wsEdges = wsImg == 0;
            wsEdges = bwareaopen(wsEdges,200,8);
            cellMask(wsEdges) = 0;
            app.img3 = cellMask;
            togglefig('MATLAB App',true)
            imshow(app.img3,'parent',app.UIAxes_3);
            expandAxes(app.UIAxes_3);

            %morphology
            detectCircles = @(x) imfindcircles(x,[18 32], ...
	        'Sensitivity',0.89,'EdgeThreshold',0.04, ...
            'Method','TwoStage', 'ObjectPolarity','Dark');
            [centers, radii] = detectCircles(app.img2);
            
            refImg = imread('dat\B_MO1_thin_giemsa6.jpg');  
            app.img4 = imhistmatch(app.img,refImg);
            togglefig('MATLAB App',true)
            imshow(app.img4,'parent',app.UIAxes_4);
            %hold(app.UIAxes_4,"on")
            togglefig('MATLAB App',true)
            viscircles(app.UIAxes_4,centers,radii,'edgecolor','b');
            expandAxes(app.UIAxes_4);
        end

        % Button pushed function: DetectButton
        function detect(app, event)
            infectionThreshold = 143;
            gray = app.img2;

            detectCircles = @(x) imfindcircles(x,[18 32], ...
	        'Sensitivity',0.89,'EdgeThreshold',0.04, ...
            'Method','TwoStage', 'ObjectPolarity','Dark');

            [centers,radii] = detectCircles(gray);
            
            isInfected = false(numel(radii),1);
            
            refImg = imread('dat\B_MO1_thin_giemsa6.jpg');
            app.img = imhistmatch(app.img,refImg);
            
            % Creating a "mesh" can be useful:
            x = 1:size(gray,2);
            y = 1:size(gray,1);
            [xx,yy] = meshgrid(x,y);
            % xx(1:5,1:5),yy(1:5,1:5)
            togglefig('MATLAB App',true)
            imshow(app.img,'parent',app.UIAxes_5);

            %hold(app.UIAxes_5,"on")
            togglefig('MATLAB App',true)
            viscircles(app.UIAxes_5,centers,radii,'edgecolor','b');
            
            infectionMask = false(size(gray));
            for ii = 1:numel(radii)
            	mask = hypot(xx - centers(ii,1), yy - centers(ii,2)) <= radii(ii);
            	currentCellImage = gray;
            	currentCellImage(~mask) = 0;
            	infection = currentCellImage > 0 & currentCellImage < infectionThreshold;
            	infectionMask = infectionMask | infection;
            	isInfected(ii) = any(infection(:));
            	if isInfected(ii)
            		showMaskAsOverlay(0.3,mask,'g',app.UIAxes_5,false);
            	end
            end
            togglefig('MATLAB App',true)
            showMaskAsOverlay(0.5,infectionMask,'r',app.UIAxes_5,false);
            expandAxes(app.UIAxes_5);


            if sum(isInfected)>0
                app.DetectionResultEditField.Value = "Infected";
            else
                app.DetectionResultEditField.Value = "not Infected";
            end

            app.InfectedEditField.Value = sprintf( ...
                '%i of %i (%0.1f%%)', sum(isInfected), ...
                numel(isInfected),100*sum(isInfected)/numel(isInfected));
        end

        % Button pushed function: ResetButton
        function reset(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);
            cla(app.UIAxes_4);
            cla(app.UIAxes_5);
            app.DetectionResultEditField.Value = ""; 
            app.InfectedEditField.Value = "";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'preprocess')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Watersred')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Morphological')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Detect')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.InputButton = uibutton(app.UIFigure, 'push');
            app.InputButton.ButtonPushedFcn = createCallbackFcn(app, @inputIMG, true);
            app.InputButton.Text = 'Input';
            app.preprocessButton = uibutton(app.UIFigure, 'push');
            app.preprocessButton.ButtonPushedFcn = createCallbackFcn(app, @preProcess, true);
            app.preprocessButton.Text = 'preprocess';
            app.SefmentationButton = uibutton(app.UIFigure, 'push');
            app.SefmentationButton.ButtonPushedFcn = createCallbackFcn(app, @segmentation, true);
            app.SefmentationButton.Text = 'Sefmentation';
            app.DetectButton = uibutton(app.UIFigure, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @detect, true);
            app.DetectButton.Text = 'Detect';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @reset, true);
            app.ResetButton.Text = 'Reset';
            app.InfectedEditFieldLabel = uilabel(app.UIFigure);
            app.InfectedEditFieldLabel.Text = '% Infected';
            app.InfectedEditField = uieditfield(app.UIFigure, 'text');
            app.InfectedEditField.Editable = 'off';
            app.DetectionResultEditFieldLabel = uilabel(app.UIFigure);
            app.DetectionResultEditFieldLabel.Text = 'Detection Result';
            app.DetectionResultEditField = uieditfield(app.UIFigure, 'text');
            app.DetectionResultEditField.Editable = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '🅼🅸🅰🆂🅼🅰🅳🅴🆃🅴🅲🆃';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Malaria_D

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end